classdef BMSapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        QuitButton                     matlab.ui.control.Button
        EditField                      matlab.ui.control.EditField
        AlertLamp                      matlab.ui.control.Lamp
        AlertLampLabel                 matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        OverviewTab                    matlab.ui.container.Tab
        RPMEditField                   matlab.ui.control.NumericEditField
        RPMEditFieldLabel              matlab.ui.control.Label
        Solar                          matlab.ui.control.NumericEditField
        SolarWEditFieldLabel           matlab.ui.control.Label
        RangekmEditFieldLabel          matlab.ui.control.Label
        Range                          matlab.ui.control.NumericEditField
        GoalkmEditField                matlab.ui.control.NumericEditField
        GoalkmEditFieldLabel           matlab.ui.control.Label
        Usage                          matlab.ui.control.NumericEditField
        PowerkWEditFieldLabel          matlab.ui.control.Label
        Lamp_3                         matlab.ui.control.Lamp
        Lamp_2                         matlab.ui.control.Lamp
        Lamp                           matlab.ui.control.Lamp
        Capacity                       matlab.ui.control.NumericEditField
        CapacityLabel                  matlab.ui.control.Label
        TempC                          matlab.ui.control.NumericEditField
        TempCEditFieldLabel            matlab.ui.control.Label
        Speed                          matlab.ui.control.NumericEditField
        SpeedkmhEditFieldLabel         matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        BatteryTab                     matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        BMSTab                         matlab.ui.container.Tab
        TAUX11                         matlab.ui.control.NumericEditField
        TAUX11EditFieldLabel           matlab.ui.control.Label
        TAUX10                         matlab.ui.control.NumericEditField
        TAUX10EditFieldLabel           matlab.ui.control.Label
        TAUX9                          matlab.ui.control.NumericEditField
        TAUX9EditFieldLabel            matlab.ui.control.Label
        TAUX8                          matlab.ui.control.NumericEditField
        TAUX8EditFieldLabel            matlab.ui.control.Label
        TAUX7                          matlab.ui.control.NumericEditField
        TAUX7EditFieldLabel            matlab.ui.control.Label
        TAUX6                          matlab.ui.control.NumericEditField
        TAUX6EditFieldLabel            matlab.ui.control.Label
        TAUX5                          matlab.ui.control.NumericEditField
        TAUX5EditFieldLabel            matlab.ui.control.Label
        TAUX4                          matlab.ui.control.NumericEditField
        TAUX4EditFieldLabel            matlab.ui.control.Label
        TAUX3                          matlab.ui.control.NumericEditField
        TAUX3EditFieldLabel            matlab.ui.control.Label
        TAUX2                          matlab.ui.control.NumericEditField
        TAUX2EditFieldLabel            matlab.ui.control.Label
        TAUX1                          matlab.ui.control.NumericEditField
        TAUX1EditFieldLabel            matlab.ui.control.Label
        CMUstatusTab                   matlab.ui.container.Tab
        HighestcelltemperatureLabel    matlab.ui.control.Label
        TemperatureEditField_2         matlab.ui.control.NumericEditField
        TemperatureEditField_2Label    matlab.ui.control.Label
        CellEditField_4                matlab.ui.control.NumericEditField
        CellEditField_4Label           matlab.ui.control.Label
        CellCMUEditField_4             matlab.ui.control.NumericEditField
        CellCMUEditField_4Label        matlab.ui.control.Label
        LowestcelltemperatureLabel     matlab.ui.control.Label
        TemperatureEditField           matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel      matlab.ui.control.Label
        CellEditField_3                matlab.ui.control.NumericEditField
        CellEditField_3Label           matlab.ui.control.Label
        CellCMUEditField_3             matlab.ui.control.NumericEditField
        CellCMUEditField_3Label        matlab.ui.control.Label
        HighestcellvoltageLabel        matlab.ui.control.Label
        VoltagemVEditField_2           matlab.ui.control.NumericEditField
        VoltagemVEditField_2Label      matlab.ui.control.Label
        CellEditField_2                matlab.ui.control.NumericEditField
        CellEditField_2Label           matlab.ui.control.Label
        CellCMUEditField_2             matlab.ui.control.NumericEditField
        CellCMUEditField_2Label        matlab.ui.control.Label
        CellEditField                  matlab.ui.control.NumericEditField
        CellEditFieldLabel             matlab.ui.control.Label
        LowestcellvoltageLabel         matlab.ui.control.Label
        CellCMUEditField               matlab.ui.control.NumericEditField
        CellCMUEditFieldLabel          matlab.ui.control.Label
        VoltagemVEditField             matlab.ui.control.NumericEditField
        VoltagemVEditFieldLabel        matlab.ui.control.Label
        SettingsTab                    matlab.ui.container.Tab
        numCMU                         matlab.ui.control.NumericEditField
        numCMUEditFieldLabel           matlab.ui.control.Label
        WeightkgEditField              matlab.ui.control.NumericEditField
        WeightkgEditFieldLabel         matlab.ui.control.Label
        TravelhourshEditField          matlab.ui.control.NumericEditField
        TravelhourshEditFieldLabel     matlab.ui.control.Label
        TraveldistancemEditField       matlab.ui.control.NumericEditField
        TraveldistancemEditFieldLabel  matlab.ui.control.Label
        CANTab                         matlab.ui.container.Tab
        RequestchargeButton            matlab.ui.control.Button
        RequestloadButton              matlab.ui.control.Button
        CANinputSwitch                 matlab.ui.control.Switch
        CANinputSwitchLabel            matlab.ui.control.Label
        DF8                            matlab.ui.control.NumericEditField
        DF8EditFieldLabel              matlab.ui.control.Label
        DF7                            matlab.ui.control.NumericEditField
        DF7EditFieldLabel              matlab.ui.control.Label
        DF6                            matlab.ui.control.NumericEditField
        DF6EditFieldLabel              matlab.ui.control.Label
        DF5                            matlab.ui.control.NumericEditField
        DF5EditFieldLabel              matlab.ui.control.Label
        DF4                            matlab.ui.control.NumericEditField
        DF4EditFieldLabel              matlab.ui.control.Label
        DF3                            matlab.ui.control.NumericEditField
        DF3EditFieldLabel              matlab.ui.control.Label
        DF2                            matlab.ui.control.NumericEditField
        DF2EditFieldLabel              matlab.ui.control.Label
        DF1                            matlab.ui.control.NumericEditField
        DF1EditFieldLabel              matlab.ui.control.Label
        Len                            matlab.ui.control.NumericEditField
        LenEditFieldLabel              matlab.ui.control.Label
        RTR                            matlab.ui.control.NumericEditField
        RTREditFieldLabel              matlab.ui.control.Label
        ID                             matlab.ui.control.NumericEditField
        IDEditFieldLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        Timer = 0;  % Timer for updating the GUI with Matlab script values
        tabs
        TabVol      % CMU cell voltage tab
    end
    
    methods (Access = public)
        
        function TimerCallback(app, obj, event)
            % General data
            [dSpeed, dSOH, dSOC, dCap, dSol, dUsage, dRange] = passData();
            app.Speed.Value = dSpeed*3.6;
            app.Capacity.Value = dSOC*100;
            app.Usage.Value = dUsage*0.001;
            app.Solar.Value = dSol;
            app.Range.Value = dRange*0.001;
            
            % CAN data display
            %[ID,RTR,Len,Data] = RxCan(Ch1);
            %app.ID.Value = ID;
            %app.RTR.Value = RTR;
            %app.Len.Value = Len;
            %app.DF1.Value = bitand(bitsrl(Data, 56),255);
            %app.DF2.Value = bitand(bitsrl(Data, 48),255);
            %app.DF3.Value = bitand(bitsrl(Data, 40),255);
            %app.DF4.Value = bitand(bitsrl(Data, 32),255);
            %app.DF5.Value = bitand(bitsrl(Data, 24),255);
            %app.DF6.Value = bitand(bitsrl(Data, 16),255);
            %app.DF7.Value = bitand(bitsrl(Data, 8),255);
            %app.DF8.Value = bitand(Data,255);
            
            % Temperature
            switch ID
                case 20     % PACK_Q_REMAINING_NOMINAL
                    app.Capacity.Value = Data;
                case 138    % AUX_T1
                    app.TAUX1.Value = Data;
                case 139    % AUX_T2
                    app.TAUX2.Value = Data;
                case 140    % AUX_T3
                    app.TAUX3.Value = Data;
                case 141    % AUX_T4
                    app.TAUX4.Value = Data;
                case 142    % AUX_T5
                    app.TAUX5.Value = Data;
                case 143    % AUX_T6
                    app.TAUX6.Value = Data;
                case 144    % AUX_T7
                    app.TAUX7.Value = Data;
                case 145    % AUX_T8
                    app.TAUX8.Value = Data;
                case 146    % AUX_T9
                    app.TAUX9.Value = Data;
                case 147    % AUX_T10
                    app.TAUX10.Value = Data;
                case 148    % AUX_T11
                    app.TAUX11.Value = Data;
                case 149    % 
                    app.TAUX3.Value = Data;
                case 971    % CELL_T_AVG
                    app.TempC.Value = Data;
                otherwise
                    % Implement something
            end
        end
        
        function numTab(app, obj, event)
            % Creates sub tab groups for the voltage tab
            
            % Set up tab group
            app.TabVol = uitabgroup(app.CMUstatusTab); % app.TabGroup
            app.TabVol.Position = [0, 62, 325, 350];
            
            % Specify the total number of tabs
            nTabs = app.numCMU.Value;    % Including MainTab
            
            % Set up the main tab
            app.tabs = cell(1,nTabs);
            app.tabs{1} = uitab(app.TabVol,'Title','CMU 1'); % Create main tab
            
            % Setup table for values
            uit = uitable('Parent',app.tabs{1});
            load("dummycelldata.mat","-mat","CMU");
            t = table(CMU{1,1}.Number,CMU{1,1}.Voltage,CMU{1,1}.Temperature);
            uit.Data = t;
            uit.ColumnName = {'Cell number','Voltage','Temperature'};
            uit.ColumnWidth = {'auto','auto','auto'};
            
            % Create additional tabs
            for i = 2:nTabs
                app.tabs{i} = uitab(app.TabVol,'Title',['CMU ' num2str(i)]);
                uit = uitable('Parent',app.tabs{i});

                t = table(CMU{1,i}.Number,CMU{1,i}.Voltage,CMU{1,i}.Temperature);
                uit.Data = t;
                uit.ColumnName = {'Cell number','Voltage','Temperature'};
                uit.ColumnWidth = {'auto','auto','auto'};
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Timer for continuous update
            app.Timer = timer('Period',1,'ExecutionMode','fixedSpacing','TasksToExecute', Inf);
            app.Timer.TimerFcn = @app.TimerCallback; % Timer callback
            start(app.Timer);
            app.numTab;
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            stop(app.Timer);
            %close(app.UIFigure);
        end

        % Button pushed function: RequestloadButton
        function RequestloadButtonPushed(app, event)
            % Setup CAN message
            txmessage = canMessage(291,false,8);
            % Data, any value other than zero will trigger an active request
            load = 1;
            charge = 0;
            staging = 0;
            % Compiling message
            txmessage.Data = ([0 0 0 0 0 staging charge load])
            % txmessage.Remote = true;
            % Selecting the CAN channel
            txcanch = canChannel('PEAK-System','PCAN_USBBUS1', 1);
            start(txcanch)
            transmit(txcanch,txmessage)
            % Receive staging request
            rxCanCh = canChannel('PEAK-System','PCAN_USBBUS1', 1);
            filterAllowOnly(rxCanCh, 291, "Standard");
            rxmessage = receive(rxCanCh, Inf)
            % Wait for staging request
            i = 0;
            stgFlag = 0;
            while stgFlag ~= 1
                transmit(txcanch,txmessage)
                rxmessage = receive(rxCanCh, Inf)
                i = i + 1;
                if rxmessage.Data[5] == 1
                    stgFlag = 1;
                elseif i == 500
                    break
                end
            end
            if stgFlag == 1
                staging = 1;
                txmessage.Data = ([0 0 0 0 0 staging charge load])
                transmit(txcanch,txmessage)
            end
            
        end

        % Button pushed function: RequestchargeButton
        function RequestchargeButtonPushed(app, event)
            % Setup CAN message
            message = canMessage(291,false,8);
            % Data, any value other than zero will trigger an active request
            load = 0;
            charge = 1;
            staging = 0;
            % Compiling message
            message.Data = ([0 0 0 0 0 staging charge load])
            % message.Remote = true;
            % Selecting the CAN channel
            canch = canChannel('MathWorks','Virtual 1', 1);
            start(canch)
            transmit(canch,message)
        end

        % Callback function
        function RequeststagingButtonPushed(app, event)
            % Setup CAN message
            message = canMessage(291,false,8);
            % Data, any value other than zero will trigger an active request
            load = 0;
            charge = 0;
            staging = 1;
            % Compiling message
            message.Data = ([0 0 0 0 0 staging charge load])
            % message.Remote = true;
            % Selecting the CAN channel
            canch = canChannel('MathWorks','Virtual 1', 1);
            start(canch)
            transmit(canch,message)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.UIAxes = uiaxes(app.OverviewTab);
            title(app.UIAxes, 'Optimal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SpeedkmhEditFieldLabel = uilabel(app.OverviewTab);
            app.SpeedkmhEditFieldLabel.Text = 'Speed [km/h]';
            app.Speed = uieditfield(app.OverviewTab, 'numeric');
            app.Speed.Editable = 'off';
            app.TempCEditFieldLabel = uilabel(app.OverviewTab);
            app.TempCEditFieldLabel.Text = 'Temp [°C]';
            app.TempC = uieditfield(app.OverviewTab, 'numeric');
            app.TempC.Editable = 'off';
            app.CapacityLabel = uilabel(app.OverviewTab);
            app.CapacityLabel.Text = 'Capacity [%]';
            app.Capacity = uieditfield(app.OverviewTab, 'numeric');
            app.Capacity.Editable = 'off';
            app.Capacity.Value = 100;
            app.Lamp = uilamp(app.OverviewTab);
            app.Lamp_2 = uilamp(app.OverviewTab);
            app.Lamp_3 = uilamp(app.OverviewTab);
            app.PowerkWEditFieldLabel = uilabel(app.OverviewTab);
            app.PowerkWEditFieldLabel.Text = 'Power [kW]';
            app.Usage = uieditfield(app.OverviewTab, 'numeric');
            app.Usage.Editable = 'off';
            app.GoalkmEditFieldLabel = uilabel(app.OverviewTab);
            app.GoalkmEditFieldLabel.Text = 'Goal [km]';
            app.GoalkmEditField = uieditfield(app.OverviewTab, 'numeric');
            app.GoalkmEditField.Editable = 'off';
            app.GoalkmEditField.Value = 588;
            app.Range = uieditfield(app.OverviewTab, 'numeric');
            app.Range.Editable = 'off';
            app.RangekmEditFieldLabel = uilabel(app.OverviewTab);
            app.RangekmEditFieldLabel.Text = 'Range [km]';
            app.SolarWEditFieldLabel = uilabel(app.OverviewTab);
            app.SolarWEditFieldLabel.Text = 'Solar [W]';
            app.Solar = uieditfield(app.OverviewTab, 'numeric');
            app.Solar.Editable = 'off';
            app.RPMEditFieldLabel = uilabel(app.OverviewTab);
            app.RPMEditFieldLabel.Text = 'RPM';
            app.RPMEditField = uieditfield(app.OverviewTab, 'numeric');
            app.RPMEditField.Editable = 'off';
            app.BatteryTab = uitab(app.TabGroup);
            app.BatteryTab.Title = 'Battery';
            app.TabGroup2 = uitabgroup(app.BatteryTab);
            app.BMSTab = uitab(app.TabGroup2);
            app.BMSTab.Title = 'BMS';
            app.TAUX1EditFieldLabel = uilabel(app.BMSTab);
            app.TAUX1EditFieldLabel.Text = 'T-AUX-1';
            app.TAUX1 = uieditfield(app.BMSTab, 'numeric');
            app.TAUX2EditFieldLabel = uilabel(app.BMSTab);
            app.TAUX2EditFieldLabel.Text = 'T-AUX-2';
            app.TAUX2 = uieditfield(app.BMSTab, 'numeric');
            app.TAUX3EditFieldLabel = uilabel(app.BMSTab);
            app.TAUX3EditFieldLabel.Text = 'T-AUX-3';
            app.TAUX3 = uieditfield(app.BMSTab, 'numeric');
            app.TAUX4EditFieldLabel = uilabel(app.BMSTab);
            app.TAUX4EditFieldLabel.Text = 'T-AUX-4';
            app.TAUX4 = uieditfield(app.BMSTab, 'numeric');
            app.TAUX5EditFieldLabel = uilabel(app.BMSTab);
            app.TAUX5EditFieldLabel.Text = 'T-AUX-5';
            app.TAUX5 = uieditfield(app.BMSTab, 'numeric');
            app.TAUX6EditFieldLabel = uilabel(app.BMSTab);
            app.TAUX6EditFieldLabel.Text = 'T-AUX-6';
            app.TAUX6 = uieditfield(app.BMSTab, 'numeric');
            app.TAUX7EditFieldLabel = uilabel(app.BMSTab);
            app.TAUX7EditFieldLabel.Text = 'T-AUX-7';
            app.TAUX7 = uieditfield(app.BMSTab, 'numeric');
            app.TAUX8EditFieldLabel = uilabel(app.BMSTab);
            app.TAUX8EditFieldLabel.Text = 'T-AUX-8';
            app.TAUX8 = uieditfield(app.BMSTab, 'numeric');
            app.TAUX9EditFieldLabel = uilabel(app.BMSTab);
            app.TAUX9EditFieldLabel.Text = 'T-AUX-9';
            app.TAUX9 = uieditfield(app.BMSTab, 'numeric');
            app.TAUX10EditFieldLabel = uilabel(app.BMSTab);
            app.TAUX10EditFieldLabel.Text = 'T-AUX-10';
            app.TAUX10 = uieditfield(app.BMSTab, 'numeric');
            app.TAUX11EditFieldLabel = uilabel(app.BMSTab);
            app.TAUX11EditFieldLabel.Text = 'T-AUX-11';
            app.TAUX11 = uieditfield(app.BMSTab, 'numeric');
            app.CMUstatusTab = uitab(app.TabGroup2);
            app.CMUstatusTab.Title = 'CMU status';
            app.VoltagemVEditFieldLabel = uilabel(app.CMUstatusTab);
            app.VoltagemVEditFieldLabel.Text = 'Voltage [mV]';
            app.VoltagemVEditField = uieditfield(app.CMUstatusTab, 'numeric');
            app.CellCMUEditFieldLabel = uilabel(app.CMUstatusTab);
            app.CellCMUEditFieldLabel.Text = 'Cell CMU';
            app.CellCMUEditField = uieditfield(app.CMUstatusTab, 'numeric');
            app.LowestcellvoltageLabel = uilabel(app.CMUstatusTab);
            app.LowestcellvoltageLabel.Text = 'Lowest cell voltage';
            app.CellEditFieldLabel = uilabel(app.CMUstatusTab);
            app.CellEditFieldLabel.Text = 'Cell';
            app.CellEditField = uieditfield(app.CMUstatusTab, 'numeric');
            app.CellCMUEditField_2Label = uilabel(app.CMUstatusTab);
            app.CellCMUEditField_2Label.Text = 'Cell CMU';
            app.CellCMUEditField_2 = uieditfield(app.CMUstatusTab, 'numeric');
            app.CellEditField_2Label = uilabel(app.CMUstatusTab);
            app.CellEditField_2Label.Text = 'Cell';
            app.CellEditField_2 = uieditfield(app.CMUstatusTab, 'numeric');
            app.VoltagemVEditField_2Label = uilabel(app.CMUstatusTab);
            app.VoltagemVEditField_2Label.Text = 'Voltage [mV]';
            app.VoltagemVEditField_2 = uieditfield(app.CMUstatusTab, 'numeric');
            app.HighestcellvoltageLabel = uilabel(app.CMUstatusTab);
            app.HighestcellvoltageLabel.Text = 'Highest cell voltage';
            app.CellCMUEditField_3Label = uilabel(app.CMUstatusTab);
            app.CellCMUEditField_3Label.Text = 'Cell CMU';
            app.CellCMUEditField_3 = uieditfield(app.CMUstatusTab, 'numeric');
            app.CellEditField_3Label = uilabel(app.CMUstatusTab);
            app.CellEditField_3Label.Text = 'Cell';
            app.CellEditField_3 = uieditfield(app.CMUstatusTab, 'numeric');
            app.TemperatureEditFieldLabel = uilabel(app.CMUstatusTab);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.CMUstatusTab, 'numeric');
            app.LowestcelltemperatureLabel = uilabel(app.CMUstatusTab);
            app.LowestcelltemperatureLabel.Text = 'Lowest cell temperature';
            app.CellCMUEditField_4Label = uilabel(app.CMUstatusTab);
            app.CellCMUEditField_4Label.Text = 'Cell CMU';
            app.CellCMUEditField_4 = uieditfield(app.CMUstatusTab, 'numeric');
            app.CellEditField_4Label = uilabel(app.CMUstatusTab);
            app.CellEditField_4Label.Text = 'Cell';
            app.CellEditField_4 = uieditfield(app.CMUstatusTab, 'numeric');
            app.TemperatureEditField_2Label = uilabel(app.CMUstatusTab);
            app.TemperatureEditField_2Label.Text = 'Temperature';
            app.TemperatureEditField_2 = uieditfield(app.CMUstatusTab, 'numeric');
            app.HighestcelltemperatureLabel = uilabel(app.CMUstatusTab);
            app.HighestcelltemperatureLabel.Text = 'Highest cell temperature';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.TraveldistancemEditFieldLabel = uilabel(app.SettingsTab);
            app.TraveldistancemEditFieldLabel.Text = 'Travel distance [m]';
            app.TraveldistancemEditField = uieditfield(app.SettingsTab, 'numeric');
            app.TraveldistancemEditField.Value = 588000;
            app.TravelhourshEditFieldLabel = uilabel(app.SettingsTab);
            app.TravelhourshEditFieldLabel.Text = 'Travel hours [h]';
            app.TravelhourshEditField = uieditfield(app.SettingsTab, 'numeric');
            app.TravelhourshEditField.Value = 9;
            app.WeightkgEditFieldLabel = uilabel(app.SettingsTab);
            app.WeightkgEditFieldLabel.Text = 'Weight [kg]';
            app.WeightkgEditField = uieditfield(app.SettingsTab, 'numeric');
            app.WeightkgEditField.Value = 825;
            app.numCMUEditFieldLabel = uilabel(app.SettingsTab);
            app.numCMUEditFieldLabel.Text = 'numCMU';
            app.numCMU = uieditfield(app.SettingsTab, 'numeric');
            app.numCMU.Limits = [0 32];
            app.numCMU.Value = 3;
            app.CANTab = uitab(app.TabGroup);
            app.CANTab.Title = 'CAN';
            app.IDEditFieldLabel = uilabel(app.CANTab);
            app.IDEditFieldLabel.Text = 'ID';
            app.ID = uieditfield(app.CANTab, 'numeric');
            app.ID.Editable = 'off';
            app.ID.Value = 2047;
            app.RTREditFieldLabel = uilabel(app.CANTab);
            app.RTREditFieldLabel.Text = 'RTR';
            app.RTR = uieditfield(app.CANTab, 'numeric');
            app.RTR.Editable = 'off';
            app.RTR.Value = 1;
            app.LenEditFieldLabel = uilabel(app.CANTab);
            app.LenEditFieldLabel.Text = 'Len';
            app.Len = uieditfield(app.CANTab, 'numeric');
            app.Len.Editable = 'off';
            app.Len.Value = 63;
            app.DF1EditFieldLabel = uilabel(app.CANTab);
            app.DF1EditFieldLabel.Text = 'DF 1';
            app.DF1 = uieditfield(app.CANTab, 'numeric');
            app.DF1.Editable = 'off';
            app.DF1.Value = 255;
            app.DF2EditFieldLabel = uilabel(app.CANTab);
            app.DF2EditFieldLabel.Text = 'DF 2';
            app.DF2 = uieditfield(app.CANTab, 'numeric');
            app.DF2.Editable = 'off';
            app.DF2.Value = 255;
            app.DF3EditFieldLabel = uilabel(app.CANTab);
            app.DF3EditFieldLabel.Text = 'DF 3';
            app.DF3 = uieditfield(app.CANTab, 'numeric');
            app.DF3.Editable = 'off';
            app.DF3.Value = 255;
            app.DF4EditFieldLabel = uilabel(app.CANTab);
            app.DF4EditFieldLabel.Text = 'DF 4';
            app.DF4 = uieditfield(app.CANTab, 'numeric');
            app.DF4.Editable = 'off';
            app.DF4.Value = 255;
            app.DF5EditFieldLabel = uilabel(app.CANTab);
            app.DF5EditFieldLabel.Text = 'DF 5';
            app.DF5 = uieditfield(app.CANTab, 'numeric');
            app.DF5.Editable = 'off';
            app.DF5.Value = 255;
            app.DF6EditFieldLabel = uilabel(app.CANTab);
            app.DF6EditFieldLabel.Text = 'DF 6';
            app.DF6 = uieditfield(app.CANTab, 'numeric');
            app.DF6.Editable = 'off';
            app.DF6.Value = 255;
            app.DF7EditFieldLabel = uilabel(app.CANTab);
            app.DF7EditFieldLabel.Text = 'DF 7';
            app.DF7 = uieditfield(app.CANTab, 'numeric');
            app.DF7.Editable = 'off';
            app.DF7.Value = 255;
            app.DF8EditFieldLabel = uilabel(app.CANTab);
            app.DF8EditFieldLabel.Text = 'DF 8';
            app.DF8 = uieditfield(app.CANTab, 'numeric');
            app.DF8.Editable = 'off';
            app.DF8.Value = 255;
            app.CANinputSwitchLabel = uilabel(app.CANTab);
            app.CANinputSwitchLabel.Text = 'CAN input';
            app.CANinputSwitch = uiswitch(app.CANTab, 'slider');
            app.CANinputSwitch.Items = {'Serial', 'UDP'};
            app.CANinputSwitch.Value = 'Serial';
            app.RequestloadButton = uibutton(app.CANTab, 'push');
            app.RequestloadButton.ButtonPushedFcn = createCallbackFcn(app, @RequestloadButtonPushed, true);
            app.RequestloadButton.Text = 'Request load';
            app.RequestchargeButton = uibutton(app.CANTab, 'push');
            app.RequestchargeButton.ButtonPushedFcn = createCallbackFcn(app, @RequestchargeButtonPushed, true);
            app.RequestchargeButton.Text = 'Request charge';
            app.AlertLampLabel = uilabel(app.UIFigure);
            app.AlertLampLabel.Text = 'Alert';
            app.AlertLamp = uilamp(app.UIFigure);
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BMSapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end